classdef DeceptionDice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CHALLENGEButton              matlab.ui.control.Button
        CONTINUEButton               matlab.ui.control.StateButton
        NumberSpinner                matlab.ui.control.Spinner
        DiceFaceSpinner              matlab.ui.control.Spinner
        P2ROLLButton                 matlab.ui.control.StateButton
        TextArea                     matlab.ui.control.TextArea
        GameHistoryLabel             matlab.ui.control.Label
        RESTARTButton                matlab.ui.control.StateButton
        P1ROLLButton                 matlab.ui.control.StateButton
        Image12                      matlab.ui.control.Image
        Image11                      matlab.ui.control.Image
        ORLabel                      matlab.ui.control.Label
        DiceFaceSpinnerLabel         matlab.ui.control.Label
        NumberSpinnerLabel           matlab.ui.control.Label
        Player2Label                 matlab.ui.control.Label
        P2Dice5                      matlab.ui.control.Image
        P2Dice4                      matlab.ui.control.Image
        P2Dice3                      matlab.ui.control.Image
        P2Dice2                      matlab.ui.control.Image
        P2Dice1                      matlab.ui.control.Image
        P1Dice5                      matlab.ui.control.Image
        P1Dice4                      matlab.ui.control.Image
        P1Dice3                      matlab.ui.control.Image
        P1Dice2                      matlab.ui.control.Image
        P1Dice1                      matlab.ui.control.Image
        RoundTable                   matlab.ui.control.Table
        HELPButton                   matlab.ui.control.Button
        STARTButton                  matlab.ui.control.StateButton
        EnterYourNameEditField       matlab.ui.control.EditField
        EnterYourNameEditFieldLabel  matlab.ui.control.Label
        P1Image                      matlab.ui.control.Image
        P2Image                      matlab.ui.control.Image
    end


    properties (Access = public)
        oldNumBid = 1
        oldFaceBid = 2
        trueOrFalse
        validOrInvalid
        numP1D1
        numP1D2
        numP1D3
        numP1D4
        numP1D5
        numP2D1
        numP2D2
        numP2D3
        numP2D4
        numP2D5
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reset Dices
            ResetDice(app);
            % background music

            % Hide Game botton
            app.NumberSpinner.Visible = 'off';
            app.DiceFaceSpinner.Visible = 'off';
            app.CONTINUEButton.Visible = 'off';
            app.CHALLENGEButton.Visible = 'off';
            app.Image11.Visible = 'off';
            app.Image12.Visible = 'off';
            app.ORLabel.Visible = 'off';
            app.P1ROLLButton.Visible = 'off';
            app.P2ROLLButton.Visible = 'off';

            % TextArea Message

            app.RoundTable.RowName
        end

        % Value changed function: CONTINUEButton
        function CONTINUEButtonValueChanged(app, event)
            % Assign the number and face according to the inputs
            numBid = app.NumberSpinner.Value;
            faceBid = app.DiceFaceSpinner.Value;
            % Call a function to check the validity of the inputs
            validTest(numBid,faceBid,app); 
            if app.trueOrFalse
                app.validOrInvalid = "Nice Bid! Here is other player's turn!";
                app.oldNumBid = numBid;
                app.oldFaceBid = faceBid;
                TextAreaValueChanged(app,event);
            else
                app.validOrInvalid = "Invalid input! Please try AGAIN!";
                TextAreaValueChanged(app,event);
            end

            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            % How to storage the old data and print the new in a new line?

              % app.TextArea.Value = app.TextArea.Value + '\n' + app.validOrInvalid;
                % Error
              % app.TextArea.Value = app.TextArea.Value + app.validOrInvalid;
                % Cannot next line
              % app.TextArea.Value = app.validOrInvalid;
                % No menory
                
            d = {1,'Win','Lost'};
            f = {app.RoundTable.Data(1)+1,'Lost','Win'};

            app.RoundTable.Data = d+f


        end

        % Value changed function: RESTARTButton
        function RESTARTButtonValueChanged(app, event)
            startupFcn(app);
            
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            app.NumberSpinner.Visible = 'on';
            app.DiceFaceSpinner.Visible = 'on';
            app.CONTINUEButton.Visible = 'on';
            app.CHALLENGEButton.Visible = 'on';
            app.Image11.Visible = 'on';
            app.Image12.Visible = 'on';
            app.ORLabel.Visible = 'on';
            app.P1ROLLButton.Visible = 'on';
            app.P2ROLLButton.Visible = 'on';
            
        end

        % Value changed function: P1ROLLButton
        function P1ROLLButtonValueChanged(app, event)
            P1RandInt1_6(app);

        end

        % Value changed function: P2ROLLButton
        function P2ROLLButtonValueChanged(app, event)
            P2RandInt1_6(app);

        end

        % Button pushed function: CHALLENGEButton
        function CHALLENGEButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.P2Image = uiimage(app.UIFigure);
            app.P2Image.ImageSource = 'defaultpic2.jpg';
            app.P1Image = uiimage(app.UIFigure);
            app.P1Image.ImageSource = 'defaultpic1.jpg';
            app.EnterYourNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterYourNameEditFieldLabel.Text = 'Enter Your Name:';
            app.EnterYourNameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterYourNameEditField.Value = 'Player 1';
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.HELPButton = uibutton(app.UIFigure, 'push');
            app.HELPButton.Icon = 'help.jpg';
            app.HELPButton.Text = '';
            app.RoundTable = uitable(app.UIFigure);
            app.RoundTable.ColumnName = {'Round'; 'Player 1'; 'Player 2'};
            app.RoundTable.RowName = {};
            app.RoundTable.ColumnEditable = true;
            app.P1Dice1 = uiimage(app.UIFigure);
            app.P1Dice1.ImageSource = 'defaultdice.png';
            app.P1Dice2 = uiimage(app.UIFigure);
            app.P1Dice2.ImageSource = 'defaultdice.png';
            app.P1Dice3 = uiimage(app.UIFigure);
            app.P1Dice3.ImageSource = 'defaultdice.png';
            app.P1Dice4 = uiimage(app.UIFigure);
            app.P1Dice4.ImageSource = 'defaultdice.png';
            app.P1Dice5 = uiimage(app.UIFigure);
            app.P1Dice5.ImageSource = 'defaultdice.png';
            app.P2Dice1 = uiimage(app.UIFigure);
            app.P2Dice1.ImageSource = 'defaultdice.png';
            app.P2Dice2 = uiimage(app.UIFigure);
            app.P2Dice2.ImageSource = 'defaultdice.png';
            app.P2Dice3 = uiimage(app.UIFigure);
            app.P2Dice3.ImageSource = 'defaultdice.png';
            app.P2Dice4 = uiimage(app.UIFigure);
            app.P2Dice4.ImageSource = 'defaultdice.png';
            app.P2Dice5 = uiimage(app.UIFigure);
            app.P2Dice5.ImageSource = 'defaultdice.png';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.NumberSpinnerLabel = uilabel(app.UIFigure);
            app.NumberSpinnerLabel.Text = 'Number';
            app.DiceFaceSpinnerLabel = uilabel(app.UIFigure);
            app.DiceFaceSpinnerLabel.Text = 'Dice Face ';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.Image11 = uiimage(app.UIFigure);
            app.Image11.ImageSource = 'frame.png';
            app.Image12 = uiimage(app.UIFigure);
            app.Image12.ImageSource = 'frame.png';
            app.P1ROLLButton = uibutton(app.UIFigure, 'state');
            app.P1ROLLButton.ValueChangedFcn = createCallbackFcn(app, @P1ROLLButtonValueChanged, true);
            app.P1ROLLButton.Text = 'ROLL！';
            app.RESTARTButton = uibutton(app.UIFigure, 'state');
            app.RESTARTButton.ValueChangedFcn = createCallbackFcn(app, @RESTARTButtonValueChanged, true);
            app.RESTARTButton.Text = 'RESTART';
            app.GameHistoryLabel = uilabel(app.UIFigure);
            app.GameHistoryLabel.Text = 'Game History';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.P2ROLLButton = uibutton(app.UIFigure, 'state');
            app.P2ROLLButton.ValueChangedFcn = createCallbackFcn(app, @P2ROLLButtonValueChanged, true);
            app.P2ROLLButton.Text = 'ROLL！';
            app.DiceFaceSpinner = uispinner(app.UIFigure);
            app.DiceFaceSpinner.Limits = [2 6];
            app.DiceFaceSpinner.Value = 2;
            app.NumberSpinner = uispinner(app.UIFigure);
            app.NumberSpinner.Limits = [1 10];
            app.NumberSpinner.Value = 1;
            app.CONTINUEButton = uibutton(app.UIFigure, 'state');
            app.CONTINUEButton.ValueChangedFcn = createCallbackFcn(app, @CONTINUEButtonValueChanged, true);
            app.CONTINUEButton.Text = 'CONTINUE';
            app.CHALLENGEButton = uibutton(app.UIFigure, 'push');
            app.CHALLENGEButton.ButtonPushedFcn = createCallbackFcn(app, @CHALLENGEButtonPushed, true);
            app.CHALLENGEButton.Text = 'CHALLENGE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeceptionDice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end